classdef LPR_SIS_Interface < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        LicensePlateRecognitionStateIdentificationSystemPanel  matlab.ui.container.Panel
        GroupATanZuSengTP065401VoonJinHuiTP064878Label  matlab.ui.control.Label
        STARTRECOGNIZEButton           matlab.ui.control.Button
        StateRecognizedField           matlab.ui.control.EditField
        StateRecognizedEditFieldLabel  matlab.ui.control.Label
        FirstCharacterField            matlab.ui.control.EditField
        FirstCharacterEditFieldLabel   matlab.ui.control.Label
        NumberPlateField               matlab.ui.control.EditField
        NumberPlateEditFieldLabel      matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        ResultImage                    matlab.ui.control.Image
        VehicleCategoryLabel           matlab.ui.control.Label
        VehicleImagesDropDown          matlab.ui.control.DropDown
        VehicleImagesDropDownLabel     matlab.ui.control.Label
        TruckButton                    matlab.ui.control.Button
        BusButton                      matlab.ui.control.Button
        MotorcycleButton               matlab.ui.control.Button
        CarButton                      matlab.ui.control.Button
        ImageInput                     matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function img = readImageInput(comp)
            % Check if ImageInput has a source
            if isempty(comp.ImageInput.ImageSource)
                error('No image selected in ImageInput.');
            end
            
            % Read the image from the ImageSource
            imgPath = comp.ImageInput.ImageSource;
            img = imread(imgPath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTRECOGNIZEButton
        function STARTRECOGNIZEButtonPushed(comp, event)
            try
                img = readImageInput(comp);  % Read the image input
        
                % Call the external function to process everything
                [plateText, firstChar, recognizedState, imgWithBox] = detectLicensePlate_State(img);
        
                % Update the UI fields
                disp(plateText);
                disp(firstChar);
                disp(recognizedState);

                plateText = string(plateText);
                firstChar = string(firstChar);
                recognizedState = string(recognizedState);

                %comp.ResultImage.ImageSource = originalImg;
                comp.ResultImage.ImageSource = imgWithBox;
                comp.NumberPlateField.Value = plateText;
                comp.FirstCharacterField.Value = firstChar;
                comp.StateRecognizedField.Value = recognizedState;
        
            catch
                figHandle = ancestor(comp, 'figure');
                uialert(figHandle, 'Failed to read or process the image.', 'Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            % Setup button pushed functions
            comp.CarButton.ButtonPushedFcn = @(~, ~) onCarButtonPushed(comp);
            comp.BusButton.ButtonPushedFcn = @(~, ~) onBusButtonPushed(comp);
            comp.MotorcycleButton.ButtonPushedFcn = @(~, ~) onMotorcycleButtonPushed(comp);
            comp.TruckButton.ButtonPushedFcn = @(~, ~) onTruckButtonPushed(comp);
            
            % Setup dropdown value changed function
            comp.VehicleImagesDropDown.ValueChangedFcn = @(src, event) onVehicleImageSelected(comp);
            
            % Variable to store which folder is selected
            selectedFolder = '';
    
            function onCarButtonPushed(comp)
                selectedFolder = 'Car';
                updateVehicleImages(comp, selectedFolder);
            end
    
            function onBusButtonPushed(comp)
                selectedFolder = 'Bus';
                updateVehicleImages(comp, selectedFolder);
            end
    
            function onMotorcycleButtonPushed(comp)
                selectedFolder = 'Motorcycle';
                updateVehicleImages(comp, selectedFolder);
            end
    
            function onTruckButtonPushed(comp)
                selectedFolder = 'Truck';
                updateVehicleImages(comp, selectedFolder);
            end
    
            function updateVehicleImages(comp, folderName)
                % Get the folder path
                pathToMLAPP = fileparts(mfilename('fullpath'));
                folderPath = fullfile(pathToMLAPP, 'VehicleImages', folderName);
            
                % Get list of image files
                if isfolder(folderPath)
                    imageFiles = [ ...
                        dir(fullfile(folderPath, '*.png')); 
                        dir(fullfile(folderPath, '*.jpg')); 
                        dir(fullfile(folderPath, '*.jpeg')) ...
                    ];
                        
                    fileNames = {imageFiles.name}; % Cell array of filenames
            
                    % Always add 'Please Select Vehicle Image' at the start
                    comp.VehicleImagesDropDown.Items = [{'Please Select Vehicle Image'}, fileNames];
                    comp.VehicleImagesDropDown.Value = 'Please Select Vehicle Image'; % Set default selected value
                else
                    comp.VehicleImagesDropDown.Items = {'(No Images Found)'};
                    comp.VehicleImagesDropDown.Value = '(No Images Found)';
                end
            end
    
            function onVehicleImageSelected(comp)
                selectedFile = comp.VehicleImagesDropDown.Value;
    
                if isempty(selectedFile) || strcmp(selectedFile, '(No Images Found)')
                    % Clear image
                    comp.ImageInput.ImageSource = '';
                    return;
                end
    
                % Find the full path
                pathToMLAPP = fileparts(mfilename('fullpath'));
                if isempty(selectedFolder)
                    folderPath = '';
                else
                    folderPath = fullfile(pathToMLAPP, 'VehicleImages', selectedFolder);
                end
    
                fullImagePath = fullfile(folderPath, selectedFile);
    
                % Set the image source
                comp.ImageInput.ImageSource = fullImagePath;
            end
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 736 478];
            comp.BackgroundColor = [1 1 1];
            comp.LicensePlateRecognitionStateIdentificationSystemPanel = uipanel(comp);
            comp.LicensePlateRecognitionStateIdentificationSystemPanel.Title = 'License Plate Recognition & State Identification System';
            comp.ImageInput = uiimage(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.CarButton = uibutton(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'push');
            comp.CarButton.Icon = fullfile(pathToMLAPP, 'Icon', 'car.svg');
            comp.CarButton.Text = 'Car';
            comp.MotorcycleButton = uibutton(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'push');
            comp.MotorcycleButton.Icon = fullfile(pathToMLAPP, 'Icon', 'motorcycle.svg');
            comp.MotorcycleButton.Text = 'Motorcycle';
            comp.BusButton = uibutton(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'push');
            comp.BusButton.Icon = fullfile(pathToMLAPP, 'Icon', 'bus_icon.svg');
            comp.BusButton.Text = 'Bus';
            comp.TruckButton = uibutton(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'push');
            comp.TruckButton.Icon = fullfile(pathToMLAPP, 'Icon', 'truck.svg');
            comp.TruckButton.Text = 'Truck';
            comp.VehicleImagesDropDownLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.VehicleImagesDropDownLabel.Text = 'Vehicle Images';
            comp.VehicleImagesDropDown = uidropdown(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.VehicleCategoryLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.VehicleCategoryLabel.Text = 'Vehicle Category:';
            comp.ResultImage = uiimage(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.ResultsLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.ResultsLabel.Text = 'Results:';
            comp.NumberPlateEditFieldLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.NumberPlateEditFieldLabel.Text = 'Number Plate:';
            comp.NumberPlateField = uieditfield(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'text');
            comp.FirstCharacterEditFieldLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.FirstCharacterEditFieldLabel.Text = 'First Character:';
            comp.FirstCharacterField = uieditfield(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'text');
            comp.StateRecognizedEditFieldLabel = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.StateRecognizedEditFieldLabel.Text = 'State Recognized:';
            comp.StateRecognizedField = uieditfield(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'text');
            comp.STARTRECOGNIZEButton = uibutton(comp.LicensePlateRecognitionStateIdentificationSystemPanel, 'push');
            comp.STARTRECOGNIZEButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @STARTRECOGNIZEButtonPushed, true);
            comp.STARTRECOGNIZEButton.Icon = fullfile(pathToMLAPP, 'Icon', 'analyze_icon.svg');
            comp.STARTRECOGNIZEButton.Text = 'START RECOGNIZE';
            comp.GroupATanZuSengTP065401VoonJinHuiTP064878Label = uilabel(comp.LicensePlateRecognitionStateIdentificationSystemPanel);
            comp.GroupATanZuSengTP065401VoonJinHuiTP064878Label.Text = 'Group A: Tan Zu Seng (TP065401) & Voon Jin Hui (TP064878)';
        end
    end
end